classdef pruebafrecuencia < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        NombredelArchivoEditField       matlab.ui.control.EditField
        NombredelArchivoEditFieldLabel  matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        td                              matlab.ui.control.NumericEditField
        FrecuenciaInicialLabel_2        matlab.ui.control.Label
        SealdeControlPWMLabel           matlab.ui.control.Label
        Iniciar                         matlab.ui.control.StateButton
        PuertoCOM                       matlab.ui.control.DropDown
        PuertoCOMDropDownLabel          matlab.ui.control.Label
        Paso                            matlab.ui.control.NumericEditField
        PasoHzLabel                     matlab.ui.control.Label
        FrecFinal                       matlab.ui.control.NumericEditField
        FrecuenciaFinalHzEditFieldLabel  matlab.ui.control.Label
        FrecInicia                      matlab.ui.control.NumericEditField
        FrecuenciaInicialLabel          matlab.ui.control.Label
        LampArd                         matlab.ui.control.Lamp
        Image2                          matlab.ui.control.Image
        Conectar                        matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        arduinoObj % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global aux_com_ard 
            aux_com_ard = 0;
            app.Conectar.Enable = 'on';
            app.td.Enable = 'off';
            app.FrecInicia.Enable = 'off';
            app.FrecFinal.Enable = 'off';
            app.Paso.Enable = 'off';
            app.Iniciar.Enable = 'off';
            app.PuertoCOM.Enable = 'on';
            if aux_com_ard == 0
                app.LampArd.Color = 'red';
            end
            app.PuertoCOM.Items = serialportlist("available")';
        end

        % Button pushed function: Conectar
        function ConectarPushed(app, event)
            global aux_com_ard
            app.PuertoCOM.Enable = 'off';
            puertoCOM = app.PuertoCOM.Value;
            if aux_com_ard==0 || aux_com_ard==1
                try
                    app.arduinoObj = serialport(puertoCOM,9600);
                catch ME
                    errordlg(ME.message,'Error de conexión')
                    aux_com_ard = 1;
                    app.PuertoCOM.Enable = 'on';
                end
                if aux_com_ard ~= 1
                    f = waitbar(0,'Conectando...');
                    pause(0.5);
                    waitbar(.33,f,'Configurando tarjeta');
                    pause(0.5);
                    waitbar(.67,f,'Guardando configuración');
                    pause(0.5);
                    waitbar(1,f,'Terminando');
                    pause(0.5);
                    app.LampArd.Color = 'green';
                    close(f)
                    aux_com_ard = 2;
                end
            end
            if aux_com_ard==1 || aux_com_ard==0
                app.Conectar.Enable = 'on';
            end
            if aux_com_ard==2
                app.Conectar.Enable = 'off';
                app.PuertoCOM.Enable = 'off';
                app.td.Enable = 'on';
                app.FrecInicia.Enable = 'on';
                app.FrecFinal.Enable = 'on';
                app.Paso.Enable = 'on';
                app.Iniciar.Enable = 'on';
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            opc=questdlg('            ¿Desea salir?', 'Salir', 'Si', 'No', 'No');
            if strcmp (opc,'Si')
                delete(app)
                clear all;
                close all;
            end
        end

        % Callback function
        function IniciarPWMValueChanged(app, event)
            value = app.IniciarPWM.Value;
            if strcmpi(value,'on')
                app.IniciarPWM.Enable = 'off';
                app.td.Enable = 'off';
                app.FrecInicia.Enable = 'off';
                app.FrecFinal.Enable = 'off';
                app.Paso.Enable = 'off';
                frec_ini = app.FrecInicia.Value;
                frec_fin = app.FrecFinal.Value;
                paso = app.Paso.Value;
                if frec_fin > frec_ini
                    if mod(frec_fin, paso) == 0
                        disp('OK');
                        write(app.arduinoObj,frec_ini,"uint8");
                    else
                        errordlg(['No se puede alcanzar la frecuencia final' ...
                            ' con el valor de paso ingresado'],'Error');
                        app.IniciarPWM.Enable = 'on';
                        app.IniciarPWM.Value = 'Off';
                        app.td.Enable = 'on';
                        app.FrecInicia.Enable = 'on';
                        app.FrecFinal.Enable = 'on';
                        app.Paso.Enable = 'on';
                    end
                else
                    errordlg(['La frecuencia final no puede ser menor que ' ...
                        ' la frecuencia inicial'],'Error');
                    app.IniciarPWM.Enable = 'on';
                    app.IniciarPWM.Value = 'Off';
                    app.td.Enable = 'on';
                    app.FrecInicia.Enable = 'on';
                    app.FrecFinal.Enable = 'on';
                    app.Paso.Enable = 'on';
                end

            end
            
        end

        % Value changed function: Iniciar
        function IniciarValueChanged(app, event)
            value = app.Iniciar.Value;
            if value == 0
                app.Iniciar.Text = 'INICIAR';
                app.Iniciar.Icon = 'icono_play.png';
                write(app.arduinoObj,0,"uint8");
                app.td.Enable = 'on';
                app.FrecInicia.Enable = 'on';
                app.FrecFinal.Enable = 'on';
                app.Paso.Enable = 'on';
            end
            if value == 1
                app.Iniciar.Text = 'DETENER';
                app.Iniciar.Icon = 'icono_stop.png';
                app.td.Enable = 'off';
                app.FrecInicia.Enable = 'off';
                app.FrecFinal.Enable = 'off';
                app.Paso.Enable = 'off';
                frec_ini = app.FrecInicia.Value;
                frec_fin = app.FrecFinal.Value;
                paso = app.Paso.Value;
                n = app.td.Value;
                if frec_fin > frec_ini
                    %Cuando ya funciona la app
                    if mod(frec_fin, paso) == 0

                        % Obtener el nombre del cuadro de texto
                        nombre = app.NombredelArchivoEditField.Value;

                        % Crear el nombre de la carpeta
                        folderName = sprintf('%s_Medidas', nombre);

                        % Crear la carpeta si no existe
                        if ~exist(folderName, 'dir')
                            mkdir(folderName);
                        end

                       
                        for td = 1:n

                            num_frec = (frec_fin-frec_ini)/paso + 1;

                            resultados = zeros(1, num_frec);%Guarda mean y peak-peak
                            frecuencia = zeros(1, num_frec);
                            stdmeanvoltage = zeros(1, num_frec);
                            stdppvoltage = zeros(1, num_frec);
                            stdvoltage = zeros(1, num_frec);


                            cont = 1;
                            

                            for frec = frec_ini:paso:frec_fin


                                % Definición del número de muestras a tomar
                                periodo = 1/frec;
                                %Muestras por periodo
                                muest_unper = periodo/0.000016;
                                %Total de muestras a tomar en n periodos
                                n=5;
                                muestras = n*ceil(muest_unper) + 500; %Se dejan 500 muestras sin analizar porque a veces falla la toma de esos primeros datos hasta que se estabilice el sistema

                                % Definición del número de muestras a tomar
                                %periodo = 1/frec;
                                %muest_unper = periodo/0.000016;
                                %muestras = 5*ceil(muest_unper) + 1000;


                                write(app.arduinoObj,frec,"uint8");

                                
                                voltaje = zeros(1, muestras); % Initialize voltage array
                                tiempo = zeros(1, muestras); % Initialize time array
                             

                                % Wait for 10 seconds before starting the data acquisition
                                pause(10);


                                % Adquiere datos hasta el número 'muestras'
                                for contador = 1:muestras
                                    valorADC = str2double(readline(app.arduinoObj));

                                    % Calculate voltage
                                    voltaje(contador) = valorADC;

                                    % Plot data in UIAxes
                                    plot(app.UIAxes, voltaje);
                                    app.UIAxes.XLim = [0 contador + 50];
                                    app.UIAxes.YLim = [0 5];

                                    % Record time
                                    %tiempo(contador) = contador / frecuencia_muestreo; % Convertir contador a tiempo en segundos
                                    tiempo(contador) = contador;
                                end


                                % Inicializar matrices para almacenar los resultados de cada periodo
                                peak_to_peak_voltages = zeros(1, n);
                                mean_voltages = zeros(1, n);


                                % Iterar a través de los datos desde 500 hasta el final, en incrementos de samples_per_period
                                period_count = 1;
                                for start_idx = 500:muest_unper:(500 + n * muest_unper - 1)
                                    end_idx = start_idx + muest_unper - 1;

                                    if end_idx > muestras
                                        break; % Salir del loop si el índice final supera el número total de muestras
                                    end

                                    % Extraer el segmento del periodo actual
                                    voltaje_period = voltaje(start_idx:end_idx);

                                    % Detectar y remover outliers
                                    mean_voltaje = mean(voltaje_period);
                                    std_voltaje = std(voltaje_period);
                                    threshold = 1.75;
                                    outliers = abs(voltaje_period - mean_voltaje) > threshold * std_voltaje;
                                    voltaje_period(outliers) = mean_voltaje; % Reemplazar outliers con el valor medio

                                    % Calcular el valor pico a pico y el valor medio para el periodo actual
                                    peak_to_peak_voltage = max(voltaje_period) - min(voltaje_period);
                                    mean_voltage = mean(voltaje_period);

                                    % Almacenar los resultados
                                    peak_to_peak_voltages(period_count) = peak_to_peak_voltage;
                                    mean_voltages(period_count) = mean_voltage;

                                    period_count = period_count + 1;
                                end

                                % Calcular el promedio y la desviación estándar de los valores pico a pico y medios
                                final_peak_to_peak_voltage = mean(peak_to_peak_voltages);
                                final_mean_voltage = mean(mean_voltages);
                                std_peak_to_peak_voltage = std(peak_to_peak_voltages);
                                std_mean_voltage = std(mean_voltages);

                                % Mostrar los resultados
                                disp(['Final Peak-to-Peak Voltage: ', num2str(final_peak_to_peak_voltage)]);
                                disp(['Final Mean Voltage: ', num2str(final_mean_voltage)]);
                                disp(['Standard Deviation of Peak-to-Peak Voltage: ', num2str(std_peak_to_peak_voltage)]);
                                disp(['Standard Deviation of Mean Voltage: ', num2str(std_mean_voltage)]);


                                resultados(cont) = final_peak_to_peak_voltage + final_mean_voltage;
                                frecuencia(cont) = frec;
                                stdmeanvoltage(cont) = std_mean_voltage;
                                stdppvoltage(cont) = std_peak_to_peak_voltage;
                                stdvoltage(cont) = std_mean_voltage + std_peak_to_peak_voltage;

                                cont=cont+1;

                            end


                            % Fit the more general exponential decay model
                            fit_type = fittype('a*exp(b*x) + c', 'independent', 'x', 'coefficients', {'a', 'b', 'c'});
                            fit_options = fitoptions('Method', 'NonlinearLeastSquares', 'StartPoint', [2, -0.1, 1]);

                            [fit_result, gof] = fit(frecuencia', resultados', fit_type, fit_options);

                            % Display the fit results
                            disp('Fit parameters:');
                            disp(fit_result);

                            disp('Goodness of fit:');
                            disp(gof);

                            % Extract fit parameters
                            a = fit_result.a;
                            b = fit_result.b;
                            c = fit_result.c;


                            % Format the equation for the legend
                            fit_equation = sprintf('y = %.3f * exp(%.3f * x) + %.3f', a, b, c);


                            % Create a table with fit parameters (horizontal)
                            fitTable = table(a, b, c);

                            % Convert table from horizontal to vertical format
                            fitTableVertical = rows2vars(fitTable);
                            fitTableVertical.Properties.VariableNames = {'Parameter', 'Value'};

                            % Goodness-of-fit statistics
                            sse = gof.sse;
                            rsquare = gof.rsquare;
                            dfe = gof.dfe;
                            adjrsquare = gof.adjrsquare;
                            rmse = gof.rmse;

                            % Create a table with goodness-of-fit statistics (horizontal)
                            gofTable = table(sse, rsquare, dfe, adjrsquare, rmse);

                            % Convert table from horizontal to vertical format
                            gofTableVertical = rows2vars(gofTable);
                            gofTableVertical.Properties.VariableNames = {'Statistic', 'Value'};

                            
                            disp('Fit parameters and goodness-of-fit statistics have been written to Excel file.');


                            %Obtención del coeficiente de difusividad y el ajuste

                            tablaaj = [frecuencia; resultados];

                            %tabla_ajuste = tablaaj';

                            % Define constants
                            w1 = 70 * 10^-6;
                            w0 = 50 * 10^-6;

                            % Define the U function
                            U = @(omega, alpha, phi, C1) 2 * pi * C1 * (1 + (1i * phi .* exp(1i * omega .* alpha^2)) / (4 * pi) ...
                                .* integral(@(x) exp(1i * omega .* alpha^2), 0, 1, 'ArrayValued', true));

                            % Define the intensity function
                            Intensidad = @(omega, alpha, phi, C1) abs(U(omega, alpha, phi, C1));

                            % Create a table and compute its length
                            omega_table = 0.1:0.5:30;

                            % Generate a random real number between -1 and 1
                            random_value = -1 + (1+1)*rand(1);

                            % Calculate intensity with noise
                            intensity_with_noise = Intensidad(10, 0.1, 1, 1) + random_value;

                            % Simulation of the intensity curve with noise
                            simulacion = arrayfun(@(w) [w, Intensidad(w, 0.1, 1, 1) + (-0.1 + (0.1+0.1)*rand(1))], omega_table, 'UniformOutput', false);
                            simulacion = cell2mat(simulacion);

                            % Nonlinear model fit
                            % Define a model function for fitting
                            model_fun = @(b, x) abs(U(x, b(1), b(2), b(3)));

                            % Adjust initial guess for parameters
                            beta0 = [0.4, 0.3, 1];  % Adjusted initial guess for parameters

                            % Fit the model to the simulation data
                            opts = statset('nlinfit');
                            opts.RobustWgtFun = 'bisquare'; % Robust fitting options

                            mdl = fitnlm(simulacion(:, 1), simulacion(:, 2), model_fun, beta0, 'Options', opts);

                            % Extract the fitted parameters
                            fitted_params = mdl.Coefficients.Estimate;

                            % Define the fitted model
                            fitted_model = @(omega) abs(U(omega, fitted_params(1), fitted_params(2), fitted_params(3)));

                            % Best fit parameters
                            best_fit_params = fitted_params;

                            % Define the data with modified intervals
                            data = tablaaj';

                            % Convert to table
                            datos = array2table(data, 'VariableNames', {'Omega', 'Intensity'});

                            % Fit the real model
                            real_mdl = fitnlm(datos.Omega, datos.Intensity, model_fun, beta0, 'Options', opts);

                            % Extract the fitted parameters for the real model
                            real_best_fit_params = real_mdl.Coefficients.Estimate;

                            % Calculate alfa
                            alfa = real_best_fit_params(1);

                            % Calculate Dif and waists
                            Dif = (w0^2 + 2 * w1^2) / alfa^2;
                            waists = sqrt(alfa^2 * 8 * 7.75 * 10^-8);

                            % Display the results
                            disp(['alfa: ', num2str(alfa)]);
                            disp(['Dif: ', num2str(Dif)]);
                            disp(['waists: ', num2str(waists)]);

                            % Display the exponential fit equation
                            disp(['Exponential Fit Equation: ', func2str(fitted_model)]);


                            %Tabla de resultados
                            results = [frecuencia; resultados; stdvoltage];

                            % Actualizar UITable con los datos transpuestos
                            %app.UITable.Data = results';

                            % Suponiendo que 'results' tiene los datos y está transpuesto correctamente
                            results_transposed = results';

                            % Crear tabla con frecuencia, voltaje y desviación estándar
                            T_data = table(results_transposed(:, 1), results_transposed(:, 2), results_transposed(:, 3), ...
                                'VariableNames', {'Frecuencia_Hz', 'Voltaje_V', 'Desviacion_Estandar'});


                            % Extraer los datos de la tabla
                            x = T_data.Frecuencia_Hz;
                            y = T_data.Voltaje_V;
                            yError = T_data.Desviacion_Estandar;

                            % Crear una nueva figura
                            fig = figure;

                            % Crear el gráfico con barras de error
                            errorbar(x, y, yError, 'o');
                            hold on;

                            % Plot the fitted curve
                            omega_fit = linspace(min(frecuencia), max(frecuencia), 100);
                            y_fit = fit_result.a * exp(fit_result.b * omega_fit) + fit_result.c;
                            plot(omega_fit, y_fit, '--', 'Color', [0.3, 0.75, 0.93], 'LineWidth', 2); % Curva ajustada en azul suave discontinua

                            % Add labels and title
                            xlabel('Frecuencia[Hz]');
                            ylabel('Voltaje[V]');
                            %title('Fit Exponencial');
                            legend('Datos Experimentales', fit_equation);
                            grid on;

                            % Display the plot
                            hold off;

                            % Obtener el nombre del cuadro de texto
                            %nombre = app.NombredelArchivoEditField.Value;

 
                            % Crear el nombre del archivo de imagen
                            filename = fullfile(folderName, sprintf('%s_td_%d.jpg', nombre, td));

                            % Guardar la figura como imagen JPG, incluyendo el valor de td en el nombre del archivo
                            % filename = sprintf('MagnetitasStev_td_%d.jpg', td);
                            % Crear el nombre del archivo de imagen
                            %filename = sprintf('%s_td_%d.jpg', nombre, td);

                            
                            saveas(fig, filename);


                            % Get current date and time
                            currentDateTime = datetime('now');

                            % Format current date and time
                            currentHour = datestr(currentDateTime, 'HH:MM:SS');
                            currentDate = datestr(currentDateTime, 'yyyy-mm-dd');

                            
                            % Create a table with hour, date, dif, and waists values
                            dataTable = table({currentHour}, {currentDate}, Dif, waists, ...
                                'VariableNames', {'Hour', 'Date', 'Dif', 'Waists'});


                            % Asignar la tabla a UITable
                            app.UITable.Data = T_data;

                          
                            % Crear el nombre del archivo de Excel
                            fileNamefin = fullfile(folderName, sprintf('%s_td%d.xlsx', nombre, td));


                            % Definir el nombre del archivo
                            %fileNamefin = sprintf('MagnetitasStev_td%d.xlsx', td);
                            % Crear el nombre del archivo de imagen
                            %fileNamefin = sprintf('%s_td_%d.xlsx', nombre, td);


                            % Escribir la primera tabla en la hoja 1
                            writetable(T_data, fileNamefin, 'Sheet', 'VoltajevsFrecuencia', 'Range', 'A1');

                            % Escribir la segunda tabla en la hoja 2
                            writetable(dataTable, fileNamefin, 'Sheet', 'Coeficientes', 'Range', 'A1');

                            % Write fit parameters to the first sheet
                            writetable(fitTableVertical, fileNamefin, 'Sheet', 'Fit Parameters');

                            % Write goodness-of-fit statistics to the second sheet
                            writetable(gofTableVertical, fileNamefin, 'Sheet', 'Goodness of Fit');


                            td=td+1;

                        end

                        % Detener la adquisición de datos y desconectar el Arduino
                        clear app.arduinoObj;
                        disp('Arduino desconectado y adquisición de datos detenida.');


                    else
                        errordlg(['No se puede alcanzar la frecuencia final' ...
                            ' con el valor de paso ingresado'],'Error');
                        app.td.Enable = 'on';
                        app.FrecInicia.Enable = 'on';
                        app.FrecFinal.Enable = 'on';
                        app.Paso.Enable = 'on';
                    end
                else
                    errordlg(['La frecuencia final no puede ser menor que ' ...
                        ' la frecuencia inicial'],'Error');
                    app.td.Enable = 'on';
                    app.FrecInicia.Enable = 'on';
                    app.FrecFinal.Enable = 'on';
                    app.Paso.Enable = 'on';
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Osciloscopio')
            xlabel(app.UIAxes, 'Tiempo (s)')
            ylabel(app.UIAxes, 'Voltaje (V)')
            zlabel(app.UIAxes, 'Z')
            app.Conectar = uibutton(app.UIFigure, 'push');
            app.Conectar.ButtonPushedFcn = createCallbackFcn(app, @ConectarPushed, true);
            app.Conectar.Text = 'CONECTAR';
            app.Conectar.Icon = fullfile(pathToMLAPP, 'icono_conectar.png');
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'arduino.png');
            app.LampArd = uilamp(app.UIFigure);
            app.FrecuenciaInicialLabel = uilabel(app.UIFigure);
            app.FrecuenciaInicialLabel.Text = {'Frecuencia '; 'Inicial'; '(Hz)'};
            app.FrecInicia = uieditfield(app.UIFigure, 'numeric');
            app.FrecuenciaFinalHzEditFieldLabel = uilabel(app.UIFigure);
            app.FrecuenciaFinalHzEditFieldLabel.Text = {'Frecuencia '; 'Final'; '(Hz)'};
            app.FrecFinal = uieditfield(app.UIFigure, 'numeric');
            app.PasoHzLabel = uilabel(app.UIFigure);
            app.PasoHzLabel.Text = 'Paso (Hz)';
            app.Paso = uieditfield(app.UIFigure, 'numeric');
            app.PuertoCOMDropDownLabel = uilabel(app.UIFigure);
            app.PuertoCOMDropDownLabel.Text = 'Puerto COM';
            app.PuertoCOM = uidropdown(app.UIFigure);
            app.PuertoCOM.Items = {};
            app.PuertoCOM.Value = {};
            app.Iniciar = uibutton(app.UIFigure, 'state');
            app.Iniciar.ValueChangedFcn = createCallbackFcn(app, @IniciarValueChanged, true);
            app.Iniciar.Text = 'INICIAR';
            app.Iniciar.Icon = fullfile(pathToMLAPP, 'icono_play.png');
            app.SealdeControlPWMLabel = uilabel(app.UIFigure);
            app.SealdeControlPWMLabel.Text = {'Señal de Control'; '(PWM)'};
            app.FrecuenciaInicialLabel_2 = uilabel(app.UIFigure);
            app.FrecuenciaInicialLabel_2.Text = 'Toma de Datos';
            app.td = uieditfield(app.UIFigure, 'numeric');
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Frecuencia (Hz)'; 'Voltaje (V)'; 'Error'};
            app.UITable.RowName = {};
            app.NombredelArchivoEditFieldLabel = uilabel(app.UIFigure);
            app.NombredelArchivoEditFieldLabel.Text = 'Nombre del Archivo';
            app.NombredelArchivoEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pruebafrecuencia

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end